classdef holoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LiveControlPanel                matlab.ui.container.Panel
        LiveControlSwitch               matlab.ui.control.ToggleSwitch
        LiveControlLamp                 matlab.ui.control.Lamp
        TabGroup2                       matlab.ui.container.TabGroup
        SceneSettingsTab                matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        TargetDisplaySwitch             matlab.ui.control.Switch
        TargetDisplaySwitchLabel        matlab.ui.control.Label
        CirclePartsCheckBox             matlab.ui.control.CheckBox
        DecreasingsizesCheckBox         matlab.ui.control.CheckBox
        MultipletargetsCheckBox         matlab.ui.control.CheckBox
        MultiTargetMaxSizepxEditField   matlab.ui.control.NumericEditField
        MultiTargetMaxSizepxEditFieldLabel  matlab.ui.control.Label
        SetFile                         matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        FileInfoLabel                   matlab.ui.control.Label
        GaussSigmaEditField             matlab.ui.control.NumericEditField
        GaussSigmaEditField_2Label      matlab.ui.control.Label
        SingleTargetSizepxEditField     matlab.ui.control.NumericEditField
        SingleTargetSizepxLabel         matlab.ui.control.Label
        BeamDiametermmEditField         matlab.ui.control.NumericEditField
        BeamDiametermmEditFieldLabel    matlab.ui.control.Label
        FocalLengthmmEditField          matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel     matlab.ui.control.Label
        BeamRowEditField                matlab.ui.control.NumericEditField
        BeamRowEditField_2Label         matlab.ui.control.Label
        BeamColEditField                matlab.ui.control.NumericEditField
        BeamColEditField_2Label         matlab.ui.control.Label
        Beam2StartingYEditField         matlab.ui.control.NumericEditField
        Beam2StartingYEditFieldLabel    matlab.ui.control.Label
        Beam2StartingXEditField         matlab.ui.control.NumericEditField
        Beam2StartingXEditFieldLabel    matlab.ui.control.Label
        Beam3StartingYEditField         matlab.ui.control.NumericEditField
        Beam3StartingYEditField_2Label  matlab.ui.control.Label
        Beam3StartingXEditField         matlab.ui.control.NumericEditField
        Beam3StartingXEditField_2Label  matlab.ui.control.Label
        Beam4StartingYEditField         matlab.ui.control.NumericEditField
        Beam4StartingYEditField_2Label  matlab.ui.control.Label
        Beam4StartingXEditField         matlab.ui.control.NumericEditField
        Beam4StartingXEditField_2Label  matlab.ui.control.Label
        Beam1StartingYEditField         matlab.ui.control.NumericEditField
        Beam1StartingYEditFieldLabel    matlab.ui.control.Label
        Beam1StartingXEditField         matlab.ui.control.NumericEditField
        Beam1StartingXEditFieldLabel    matlab.ui.control.Label
        BeamGeometryLabel               matlab.ui.control.Label
        ImagesTab                       matlab.ui.container.Tab
        RefreshFilesButton              matlab.ui.control.Button
        FilesListBox                    matlab.ui.control.ListBox
        FilesListBoxLabel               matlab.ui.control.Label
        BeamTargetsTab                  matlab.ui.container.Tab
        SeparateBeamTargetsPanel        matlab.ui.container.Panel
        Beam1TargetLabel                matlab.ui.control.Label
        Beam2TargetLabel                matlab.ui.control.Label
        Beam4TargetLabel                matlab.ui.control.Label
        Beam3TargetLabel                matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        GeometryDisplayTab              matlab.ui.container.Tab
        SLMGeometryPanel                matlab.ui.container.Panel
        SceneGeometryLabel              matlab.ui.control.Label
        TargetPatternLabel              matlab.ui.control.Label
        SLMPhaseLabel                   matlab.ui.control.Label
        SLMIlluminationLabel            matlab.ui.control.Label
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        BeamInfoTab                     matlab.ui.container.Tab
        Beam1YEditField                 matlab.ui.control.NumericEditField
        Beam1YEditFieldLabel            matlab.ui.control.Label
        Beam1XEditField                 matlab.ui.control.NumericEditField
        Beam1XEditFieldLabel            matlab.ui.control.Label
        SetButton_1                     matlab.ui.control.Button
        SetPositions_2                  matlab.ui.control.Button
        Beam4YEditField                 matlab.ui.control.NumericEditField
        Beam4YEditFieldLabel            matlab.ui.control.Label
        Beam4XEditField                 matlab.ui.control.NumericEditField
        Beam4XEditFieldLabel            matlab.ui.control.Label
        SetButton_4                     matlab.ui.control.Button
        SetGeometry                     matlab.ui.control.Button
        StartingPositions               matlab.ui.control.Button
        SetButton_2                     matlab.ui.control.Button
        SetButton_3                     matlab.ui.control.Button
        Beam2YEditField                 matlab.ui.control.NumericEditField
        Beam2YEditFieldLabel            matlab.ui.control.Label
        Beam2XEditField                 matlab.ui.control.NumericEditField
        Beam2XEditFieldLabel            matlab.ui.control.Label
        Beam3YEditField                 matlab.ui.control.NumericEditField
        Beam3YEditField_2Label          matlab.ui.control.Label
        Beam3XEditField                 matlab.ui.control.NumericEditField
        Beam3XEditField_2Label          matlab.ui.control.Label
        ConsoleTab                      matlab.ui.container.Tab
        ConsoleTextArea                 matlab.ui.control.TextArea
        ConsoleTextAreaLabel            matlab.ui.control.Label
        IOControlPanel                  matlab.ui.container.Panel
        Apply                           matlab.ui.control.Button
        SLMConnectedLamp                matlab.ui.control.Lamp
        SLMConnectedLampLabel           matlab.ui.control.Label
        APIInitializedLamp              matlab.ui.control.Lamp
        APIInitializedLampLabel         matlab.ui.control.Label
        MPUSLamp                        matlab.ui.control.Lamp
        MPUS_PCLabel                    matlab.ui.control.Label
        Save                            matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        InvertTargetButton              matlab.ui.control.Button
        LoadTargetButton                matlab.ui.control.Button
        Init                            matlab.ui.control.Button
        BeamControlPanel                matlab.ui.container.Panel
        BeamSelectionButtonGroup        matlab.ui.container.ButtonGroup
        Beam4Button                     matlab.ui.control.ToggleButton
        Beam3Button                     matlab.ui.control.ToggleButton
        Beam2Button                     matlab.ui.control.ToggleButton
        Beam1Button                     matlab.ui.control.ToggleButton
        BeamMovementPanel               matlab.ui.container.Panel
        PixelsSlider                    matlab.ui.control.Slider
        PixelsSliderLabel               matlab.ui.control.Label
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        ImageDisplay2Label              matlab.ui.control.Label
        ImageDisplay1Label              matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        img 
        waiting
        target
        nCol
        nRow
        focal
        beamDiam
        config
        targetArr
        targetArrSmall
        incShift
        totResult
        totPhase
        totInten
        fftArr
        saveTitle
        outFile
        outDir
        sizeRatio
        SLM
        beamShift
        beamGauss
        sceneGeom
        num_boards_found
        constructed_okay
        currentImg
        baseImg
        SDK
        appConfig
        dispTarget
        dispResult
        X
        Y
        currPath
    end
    
    methods (Access = private)
        
        function liveSet = setLiveImg(app, i)
            
            rho = sqrt(app.config.targShiftX(i).^2 + app.config.targShiftY(i).^2);
            phi = atand(-app.config.targShiftY(i) / app.config.targShiftX(i));
            if -app.config.targShiftY(i) < 0 &&  app.config.targShiftX(i) < 0
                phi = phi + 180;
            elseif -app.config.targShiftY(i) < 0
                phi = 360 + phi;
            elseif app.config.targShiftX(i) < 0
                phi = 180+phi;
            else
                phi = phi;
            end
%             phi = phi;
            sprintf('phi: %0.0f, rho: %0.0f', phi, rho)
            spacing = 2*app.config.mapRad/rho;
            
            rotXY = [app.X(:) app.Y(:)] * [cosd(phi) -sind(phi); sind(phi) cos(phi)];
            Xr = reshape(rotXY(:,1), size(app.X,1), []);
            grating = mod(Xr, spacing)*2*pi/spacing;
            grating(isnan(grating))=0;
            imagesc(app.UIAxes_3, grating)
%             tempImg = zeros(app.SLM.dim(1), app.SLM.dim(2));
%             sprintf('currentImg size: %i', size(app.currentImg))
            imagesc(app.UIAxes_2, app.currentImg)
            imagesc(app.UIAxes_4, app.baseImg)
            xStart = app.SLM.zones(i).X(1);
            xStop = app.SLM.zones(i).X(2);
            yStart = app.SLM.zones(i).Y(1);
            yStop = app.SLM.zones(i).Y(2);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%             app.currentImg(yStart:yStop, xStart:xStop) = ...
%                 imrotate(app.currentImg(yStart:yStop, xStart:xStop), ...
%                 app.targRot(i), "crop");

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            app.currentImg(yStart:yStop, xStart:xStop) = ...
                uint8(mod(double( app.baseImg(yStart:yStop, xStart:xStop))*(2*pi/255) + ...
                grating(yStart:yStop, xStart:xStop), 2*pi)*(255/(2*pi)));
                    
%             app.currentImg = uint8(tempImg * (255/(2*pi)));
            imagesc(app.UIAxes_6, app.currentImg);
            
            app.ApplyButtonPushed(app)
            liveSet = 1;
        end
        
        function posSet = func(app, selection)
            if selection(1)
                app.Beam1XEditField.Value = app.config.targShiftX(1);
                app.Beam1YEditField.Value = app.config.targShiftY(1);
            end
            if selection(2)
                app.Beam2XEditField.Value = app.config.targShiftX(2);
                app.Beam2YEditField.Value = app.config.targShiftY(2);
            end
            if selection(3)
                app.Beam3XEditField.Value = app.config.targShiftX(3);
                app.Beam3YEditField.Value = app.config.targShiftY(3);
            end
            if selection(4)
                app.Beam4XEditField.Value = app.config.targShiftX(4);
                app.Beam4YEditField.Value = app.config.targShiftY(4);
            end
            posSet = 1;
        end
        
        function saved = saveImg(app)
            if sum(size(app.totPhase)) == 0
                disp('Nothing to apply!')
            else
                if app.LiveControlSwitch.Value ~= 1
                    phaseSave = app.totPhase(app.config.apPaddingY:app.config.apPaddingY+app.config.slmDim(1)-1, ...
                         app.config.apPaddingX:app.config.apPaddingX+app.config.slmDim(2)-1);
                    app.currentImg = uint8((phaseSave).*255./(2*pi));
                end
                    
                if app.appConfig.MPUS_PC
                    imwrite(app.currentImg, app.outFile)
                    disp('Phase map saved (RowLab-MPUS)')
                else
                    imwrite(app.currentImg, app.outFile)
                    disp('Phase map saved (local)')
                end
            end
            saved = 1;
        end
        
        function dispTargetArr = setDispTarget(app)

            if app.TargetDisplaySwitch.Value == 0
                % dispTargetArr = app.targetArrSmall(:,:,1) + ...
                %                 app.targetArrSmall(:,:,2) + ...
                %                 app.targetArrSmall(:,:,3) + ...
                %                 app.targetArrSmall(:,:,4);
                dispTargetArr = sum(app.targetArrSmall,3);
            else
                disp1 = circshift(app.targetArrSmall(:,:,1), -round(app.config.targShiftX(1)/app.sizeRatio), 2);
                disp1 = circshift(disp1, -round(app.config.targShiftY(1)/app.sizeRatio), 1);
                disp2 = circshift(app.targetArrSmall(:,:,2), -round(app.config.targShiftX(2)/app.sizeRatio), 2);
                disp2 = circshift(disp2, -round(app.config.targShiftY(2)/app.sizeRatio), 1);
                disp3 = circshift(app.targetArrSmall(:,:,3), -round(app.config.targShiftX(3)/app.sizeRatio), 2);
                disp3 = circshift(disp3, -round(app.config.targShiftY(3)/app.sizeRatio), 1);
                disp4 = circshift(app.targetArrSmall(:,:,4), -round(app.config.targShiftX(4)/app.sizeRatio), 2);
                disp4 = circshift(disp4, -round(app.config.targShiftY(4)/app.sizeRatio), 1);
                dispTargetArr = disp1 + disp2 + disp3 + disp4;
                
            end
%             imagesc(app.UIAxes_6, dispTargetArr)
        end
        
        function dispTargetsSet = setDispTargets(app)
            N_BEAM = app.config.nCol * app.config.nRow;
            dispTarg1 = circshift(app.targetArrSmall(:,:,1), -round(app.config.targShiftX(1)/app.sizeRatio), 2);
            dispTarg1 = circshift(dispTarg1, -round(app.config.targShiftY(1)/app.sizeRatio), 1);
            imagesc(app.UIAxes_7, dispTarg1)
            if N_BEAM > 1
                dispTarg2 = circshift(app.targetArrSmall(:,:,2), -round(app.config.targShiftX(2)/app.sizeRatio), 2);
                dispTarg2 = circshift(dispTarg2, -round(app.config.targShiftY(2)/app.sizeRatio), 1);
                imagesc(app.UIAxes_8, dispTarg2)
                if N_BEAM > 2
                    dispTarg3 = circshift(app.targetArrSmall(:,:,3), -round(app.config.targShiftX(3)/app.sizeRatio), 2);
                    dispTarg3 = circshift(dispTarg3, -round(app.config.targShiftY(3)/app.sizeRatio), 1);
                    imagesc(app.UIAxes_9, dispTarg3)
                    if N_BEAM > 3
                        dispTarg4 = circshift(app.targetArrSmall(:,:,4), -round(app.config.targShiftX(4)/app.sizeRatio), 2);
                        dispTarg4 = circshift(dispTarg4, -round(app.config.targShiftY(4)/app.sizeRatio), 1);
                        imagesc(app.UIAxes_10, dispTarg4)
                    end
                end
            end
            dispTargetsSet = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.appConfig = struct; %Create a struct
%             app.appConfig.sdkInit = 0;
            app.appConfig.multipleTargets = 0;
            app.appConfig.decraseSize = 0;
            app.appConfig.circPartTargets = 0;    
            app.appConfig.MPUS_PC = 0;
            app.appConfig.geomSet = 0;
            app.appConfig.targRot = [0 0 0 0];
            
            app.config = holoAppBackend;
            % app.config = evalin('base', 'config');
            % app.config = genConfig(); % get config through Glenn's function
            app.SDK.init = 0;
            app.currentImg = 0;
            app.currPath = pwd;
              
            set(app.UIAxes_1, 'XColor', 'none')
            set(app.UIAxes_1, 'YColor', 'none')
            set(app.UIAxes_1, 'Color', 'none')
            set(app.UIAxes_2, 'XColor', 'none')
            set(app.UIAxes_2, 'YColor', 'none')
            set(app.UIAxes_2, 'Color', 'none')
            set(app.UIAxes_3, 'XColor', 'none')
            set(app.UIAxes_3, 'YColor', 'none')
            set(app.UIAxes_3, 'Color', 'none')
            set(app.UIAxes_4, 'XColor', 'none')
            set(app.UIAxes_4, 'YColor', 'none')
            set(app.UIAxes_4, 'Color', 'none')
            set(app.UIAxes_5, 'XColor', 'none')
            set(app.UIAxes_5, 'YColor', 'none')
            set(app.UIAxes_5, 'Color', 'none')
            set(app.UIAxes_6, 'XColor', 'none')
            set(app.UIAxes_6, 'YColor', 'none')
            set(app.UIAxes_6, 'Color', 'none')
            
            % Q:Why is this before MPUS check? Action taken: changed file
            % accessing mode

            folderName ='..\img\tgt\';
            file = 'SLMtarget_ring3px.png'; 

            targetPath = [folderName file];

            app.target = imread(targetPath); %Get target
            app.target = double(app.target(:,:,1)); % Convert target to double
            
            %Show target in Geometry display -> SLM Geometry -> Target
            %Patten

            axis(app.UIAxes_1, 'tight')
            imagesc(app.UIAxes_2, app.target)
            colormap(app.UIAxes_2, 'gray');
            axis(app.UIAxes_2, 'tight')
            axis(app.UIAxes_3, 'tight')
            axis(app.UIAxes_4, 'tight')
            axis(app.UIAxes_6, 'tight')
            axis(app.UIAxes_7, 'tight')
            axis(app.UIAxes_8, 'tight')
            axis(app.UIAxes_9, 'tight')
            axis(app.UIAxes_10, 'tight')
            
            % MPUS check - are we using the MPUS PC? This is done by
            % checking your username
            % if getenv('username') == "lito"
            %     app.MPUSLamp.Color = [0, 1, 0]; % If yes, turn on MPUS lamp
            %     app.appConfig.MPUS_PC = 1;
            % else
            %     app.MPUSLamp.Color = [1, 0, 0];
            %     app.appConfig.MPUS_PC = 0;
            % end

            app.appConfig.MPUS_PC = 1; % For testing this will be set to true

            % Get the path to a file depending on which PC you are using

            app.outDir = '..\img\out\';

            file = app.FileNameEditField.Value; % By default, the output file is going to be 'Ovelap test 1', but it can be changed by the user 
            % (if the 'set file' button is pressed)
            app.DirectoryEditField.Value = app.outDir; % By default, the path is the one determined above but it can be changed
            app.outFile = strcat(app.outDir, file, '.bmp');
            
            % Get the checkbox values from Beam Geometry -> Target display
            app.appConfig.circPartTargets = app.config.do_circParts;
            app.appConfig.circPartTargets = app.CirclePartsCheckBox.Value;
            
            app.MultipletargetsCheckBox.Value = app.config.targetMult;
            app.appConfig.multipleTargets = app.MultipletargetsCheckBox.Value;
            % app.config.targetMult = app.MultipletargetsCheckBox.Value;

            app.appConfig.decreaseSize = app.DecreasingsizesCheckBox.Value;
            
            % Get the starting pos of the beams from Beam Geometry tab
            app.Beam1StartingXEditField.Value = app.config.startingPosX(1);
            app.Beam1StartingYEditField.Value = app.config.startingPosY(1);
            app.Beam2StartingXEditField.Value = app.config.startingPosX(2);
            app.Beam2StartingYEditField.Value = app.config.startingPosY(2);
            app.Beam3StartingXEditField.Value = app.config.startingPosX(3);
            app.Beam3StartingYEditField.Value = app.config.startingPosY(3);
            app.Beam4StartingXEditField.Value = app.config.startingPosX(4);
            app.Beam4StartingYEditField.Value = app.config.startingPosY(4);
            
            % Show the files of output ('out') folder in the 'Images' tab
            filesList = dir(app.outDir);
            app.FilesListBox.Items = {filesList.name}';

            % Determine if we have live control, depending on the switch
            if app.LiveControlSwitch.Value == 1
                app.LiveControlLamp.Color = [0, 1, 0];
            end

        end

        % Button pushed function: Init
        function InitPushed(app, event)

            
            bit_depth = 12; %bit depth = 8 for small 512, 12 for 1920
            app.num_boards_found = libpointer('uint32Ptr', 0);
            app.constructed_okay = libpointer('int32Ptr', 0);
            is_nematic_type = 1;
            RAM_write_enable = 1;
            use_GPU = 0;
            max_transients = 10;
            wait_For_Trigger = 0; % This feature is user-settable; use 1 for 'on' or 0 for 'off'
            flip_immediate = 0; % Only supported on the 1024
            timeout_ms = 5000;
            
            OutputPulseImageFlip = 0;
            OutputPulseImageRefresh = 0;
            reg_lut = libpointer('string');
            
            if ~app.appConfig.MPUS_PC
                disp('This is not the RowLab-MPUS machine!')
                return;
            end
            
            if ~libisloaded('Blink_C_wrapper')
                loadlibrary('../lib/Blink_C_wrapper.dll', '../lib/Blink_C_wrapper.h');
            end
            if ~libisloaded('ImageGen')
                loadlibrary('../lib/ImageGen.dll', '../lib/ImageGen.h');
            end
            disp('Libraries loaded.')
            disp('Trying to connect')

                disp('Trying to create SDK.')
                calllib('Blink_C_wrapper', 'Create_SDK', bit_depth, app.num_boards_found, app.constructed_okay, is_nematic_type, RAM_write_enable, use_GPU, max_transients, reg_lut); 
                if app.constructed_okay ~= 1
                    disp('SDK not constructed ok.')
                    errMsg = calllib('Blink_C_wrapper', 'Get_last_error_message');
                    disp(errMsg)
                end

            sprintf('Num boards: %i', app.num_boards_found.value)
            app.constructed_okay = app.constructed_okay;
            if app.num_boards_found.value >= 0
                app.APIInitializedLamp.Color = [0, 1, 0];
                disp('Blink SDK was successfully constructed');
                app.SLMConnectedLamp.Color = [0, 1, 0];
                board_number = 1;
                
                fprintf('Found %u SLM controller(s)\n', app.num_boards_found.value);
                
                disp('Getting SLM information.')
                height = calllib('Blink_C_wrapper', 'Get_image_height', board_number);
                width = calllib('Blink_C_wrapper', 'Get_image_width', board_number);
                depth = calllib('Blink_C_wrapper', 'Get_image_depth', board_number); %bits per pixel
                Bytes = depth/8;
                sprintf('The image height is %d bits.', height)
                sprintf('The image width is %d bits.', width)
                sprintf('The image depth is %d bits.', depth)
                sprintf('A pixel is %d bytes', Bytes)
                
                app.SDK.init = 1;
                app.SDK.board_number = 1;
                app.SDK.height = height;
                app.SDK.width = width;
                app.SDK.depth = depth;
                app.SDK.bytes = Bytes;
                app.SDK.wait_For_Trigger = wait_For_Trigger;
                app.SDK.flip_immediate = flip_immediate;
                app.SDK.OutputPulseImageFlip = OutputPulseImageFlip;
                app.SDK.OutputPulseImageRefresh = OutputPulseImageRefresh;
                app.SDK.timeout_ms = timeout_ms;
                
                %allocate arrays for our images
                ImageOne = libpointer('uint8Ptr', zeros(width*height*Bytes,1));
                ImageTwo = libpointer('uint8Ptr', zeros(width*height*Bytes,1));
                WFC = libpointer('uint8Ptr', zeros(width*height*Bytes,1));
                disp('Loading LUT.')
                calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6254_at1064_PCIe.LUT');
                disp('Writing blank image.')
                calllib('Blink_C_wrapper', 'Write_image', board_number, ImageOne, width*height*Bytes, wait_For_Trigger, flip_immediate, OutputPulseImageFlip, OutputPulseImageRefresh, timeout_ms);
                calllib('Blink_C_wrapper', 'ImageWriteComplete', board_number, timeout_ms);  
            end
        end

        % Button pushed function: LoadTargetButton
        function LoadTargetButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            
            selection = [1 1 1 1];
            dir = '..\img\tgt\';
            % if app.appConfig.MPUS_PC
            %     dir = strcat("C:\Users\Lito\OneDrive - Imperial College London\Documents 1\Υο\Education\PhD\Glenn_stuff\Code\megapix\hologram_control\img\tgt");
            % else
            %     dir = strcat("C:\Users\lito\OneDrive - Imperial College London\Documents 1\Υο\Education\PhD\Glenn_stuff\Code\megapix\hologram_control\img\tgt");
            % end
            file = uigetfile(strcat(dir, '*'));
            figure(app.UIFigure);
            app.target = imread(strcat(dir,file));
            app.target = double(app.target(:,:,1));

            imagesc(app.UIAxes_2, app.target)
            pause(0.1)
            [app.targetArr, app.targetArrSmall] = buildTargetArray(app.target, app.config.targShiftX, app.config.targShiftY, app.config);
            
            
            dispTargetArr = setDispTarget(app);
            imagesc(app.UIAxes_5, dispTargetArr)
            dispTargetsSet = setDispTargets(app);
            pause(0.01)
            posSet = func(app, selection);
            disp('Target loaded.')
        end

        % Button pushed function: InvertTargetButton
        function InvertTargetButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            
            selection = [1 1 1 1];
            app.target = app.target *-1;
            imagesc(app.UIAxes_2, app.target);
            
            [app.targetArr, app.targetArrSmall] = buildTargetArray(app.target, app.config.targShiftX, app.config.targShiftY, app.config);
            dispTargetArr = setDispTarget(app);
            imagesc(app.UIAxes_5, dispTargetArr)
            dispTargetsSet = setDispTargets(app);
            pause(0.01)
            posSet = func(app, selection);
            disp('Target inverted.')
        end

        % Button pushed function: SetGeometry
        function SetGeometryPushed(app, event)

            % Collect data from Beam Geometry tab
            focal_mm =  app.FocalLengthmmEditField.Value; % Q:Why do we need focal length?? -> Calculate Airy disk and map diameter, why? who knows
            app.nCol = app.BeamColEditField.Value; % No of columns
            app.nRow = app.BeamRowEditField.Value; % No of fields
            app.beamDiam = app.BeamDiametermmEditField.Value; % Beam diameter
            gaussSigma = app.GaussSigmaEditField.Value; % Gauss sigma
            targResize = app.SingleTargetSizepxEditField.Value; % Single target size Q: what does this measure?
            maxTargSize = app.MultiTargetMaxSizepxEditField.Value; % Multi target max size Q: what is this?
            
            % Check if we are using multiple targets from checkbox in
            % Geometry tab
            if ~app.appConfig.multipleTargets % If we don't have multiple targets
                %The 'targetResized' field is a 2x4 matrix of the 'targSize'
                % value specified in the "Single target size" field of the UI
            app.config.targetResized = [targResize, targResize, targResize, targResize; ...
                                        targResize, targResize, targResize, targResize];  
            else

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 sizeDiv = round((maxTargSize-targResize)/3);
%                 firstSize = maxTargSize;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % If we are using multiple targets
                div = 3.95/3; % Q: WTF is this

                rad0 = sqrt(maxTargSize.^2 - (0*div*maxTargSize/4).^2); % This equals max Targ Size
                rad1 = sqrt(maxTargSize.^2 - (1*div*maxTargSize/4).^2); % This equals max Targ Size * sqrt(1-div/16) = 0.958 * max Targ Size
                rad2 = sqrt(maxTargSize.^2 - (2*div*maxTargSize/4).^2); % This equals max Targ Size * sqrt(1-div/8) = 0.914 * max Targ Size
                rad3 = sqrt(maxTargSize.^2 - (3*div*maxTargSize/4).^2); % This equals max Targ Size * sqrt(1-3*div/16) = 0.8678 * max Targ Size
                
                app.config.targetResized = [rad0, rad1, rad2, rad3; ...
                                            rad0, rad1, rad2, rad3];     
             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%                 app.config.targetResized = [firstSize, firstSize-1*sizeDiv, firstSize-2*sizeDiv, firstSize-3*sizeDiv; ...
%                                             firstSize, firstSize-1*sizeDiv, firstSize-2*sizeDiv, firstSize-3*sizeDiv];   
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            end
            app.config = setParams(app.config, app.nCol, app.nRow, focal_mm, app.beamDiam, gaussSigma); % Call the set parameters function
            app.config.targRot = app.appConfig.targRot; % Determine target rotation - not especified unless you change in the code of the initialisation
            app.config.do_circParts = app.appConfig.circPartTargets; % Check if we want circle parts Q: What does this mean?
            disp(['Do circle parts?: ',convertStringsToChars(string(app.config.do_circParts))]) % Display if we are doing circle parts
            disp('Parameters set.') 
            [app.SLM, app.totInten, app.totPhase, app.fftArr, app.sceneGeom, app.beamShift, app.beamGauss] = ...
                buildMapArray(app.config); % Call buildMapArray function
            imagesc(app.UIAxes_1, imresize(app.sceneGeom, [500 500])); %display Scence geometry in  Geometry Display -> SLM Geometry -> Scence Geometry
            
            % Call buildTargetArray function
            [app.targetArr, app.targetArrSmall] = buildTargetArray(app.target, app.config.targShiftX, app.config.targShiftY, app.config);
            % fullTarget = app.targetArr(:,:,1) + app.targetArr(:,:,2) ...
            %     + app.targetArr(:,:,3) + app.targetArr(:,:,4);
            

            app.sizeRatio = size(app.targetArr(:,:,1),1) /size(app.targetArrSmall(:,:,1),1); 
            dispTargetArr = setDispTarget(app);
            imagesc(app.UIAxes_5, dispTargetArr) % Display target array in Big left Axis
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % fullTargetSmall = app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
            %     + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            axis(app.UIAxes_5, 'tight')
%             imagesc(app.UIAxes_5, fullTargetSmall)
            app.incShift = app.PixelsSlider.Value;     
            
            dispTargetsSet = setDispTargets(app);
            [app.X, app.Y] = meshgrid(-(app.config.mapRad): app.config.mapRad);
            app.X = imresize(app.X, [app.config.mapDim(1) app.config.mapDim(2)]);
            app.Y = imresize(app.Y, [app.config.mapDim(1) app.config.mapDim(2)]);
            if ~app.appConfig.geomSet
                app.currentImg = uint8(zeros(app.SLM.dim(1), app.SLM.dim(2)));
            end
            if isfile(app.outFile)
                app.baseImg = imread(app.outFile);
            else
                disp('No base image exists, creating blank image.')
                app.baseImg = uint8(zeros(app.SLM.dim(1), app.SLM.dim(2)));
            end
            disp('Geometry set.')
            app.appConfig.geomSet = 1; % Remember that geometry has been set
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            
            disp('Generating holograms.')
            for a = 1:app.nCol*app.nRow
                app.targetArr(:,:,a) = circshift(app.targetArr(:,:,a), app.config.targShiftX(a), 2);
                app.targetArr(:,:,a) = circshift(app.targetArr(:,:,a), app.config.targShiftY(a), 1);
            end
            
            % app.targetArr(:,:,2) = circshift(app.targetArr(:,:,2), app.config.targShiftX(2), 2);
            % app.targetArr(:,:,2) = circshift(app.targetArr(:,:,2), app.config.targShiftY(2), 1);
            % app.targetArr(:,:,3) = circshift(app.targetArr(:,:,3), app.config.targShiftX(3), 2);
            % app.targetArr(:,:,3) = circshift(app.targetArr(:,:,3), app.config.targShiftY(3), 1);
            % app.targetArr(:,:,4) = circshift(app.targetArr(:,:,4), app.config.targShiftX(4), 2);
            % app.targetArr(:,:,4) = circshift(app.targetArr(:,:,4), app.config.targShiftY(4), 1);
            
            
            [app.totResult,  app.totInten, app.totPhase, res] = loopBeams(app.SLM, app.beamShift, app.beamGauss, app.config.targShiftX, ...
                  app.config.targShiftY, app.fftArr, app.sceneGeom, app.totInten, ...
                  app.totPhase, app.targetArr, app.config, app.UIAxes_3, ... 
                  app.UIAxes_4, app.UIAxes_5, app.UIAxes_6);
            
            assignin('base', 'totResult', app.totResult);
            assignin('base', 'totInten', app.totInten);
            assignin('base', 'totPhase', app.totPhase);
            
            for a = 1:app.nCol*app.nRow
                app.targetArr(:,:,a) = circshift(app.targetArr(:,:,a), -app.config.targShiftX(a), 2);
                app.targetArr(:,:,a) = circshift(app.targetArr(:,:,a), -app.config.targShiftY(a), 1);
            end
            
            % app.targetArr(:,:,1) = circshift(app.targetArr(:,:,1), -app.config.targShiftX(1), 2);
            % app.targetArr(:,:,1) = circshift(app.targetArr(:,:,1), -app.config.targShiftY(1), 1);
            % app.targetArr(:,:,2) = circshift(app.targetArr(:,:,2), -app.config.targShiftX(2), 2);
            % app.targetArr(:,:,2) = circshift(app.targetArr(:,:,2), -app.config.targShiftY(2), 1);
            % app.targetArr(:,:,3) = circshift(app.targetArr(:,:,3), -app.config.targShiftX(3), 2);
            % app.targetArr(:,:,3) = circshift(app.targetArr(:,:,3), -app.config.targShiftY(3), 1);
            % app.targetArr(:,:,4) = circshift(app.targetArr(:,:,4), -app.config.targShiftX(4), 2);
            % app.targetArr(:,:,4) = circshift(app.targetArr(:,:,4), -app.config.targShiftY(4), 1);
            disp('Finished generating holograms.')
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            
            thisTargetNum = 0;
            selection = [0 0 0 0];
            for i = 1:4
                if app.BeamSelectionButtonGroup.Buttons(i).Value
                    thisTargetNum = i;
                    selection(i) = 1;
                    break
                end
            end
%             sprintf('Selection: %i', i)
            thisTarget = app.targetArrSmall(:, :, thisTargetNum) ;
            thisTarget = circshift(thisTarget, -round(app.incShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, thisTargetNum) = thisTarget;

            imagesc(app.UIAxes_5, app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
                + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4))
            
            app.config.targShiftY(i) = app.config.targShiftY(i)-app.incShift;

            app.ConsoleTextArea.Value = ['Target shifted up.'; num2str(app.config.targShiftY) ; app.ConsoleTextArea.Value];
            posSet = func(app,selection);


            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app,i);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
%             dispTargetArr = setDispTarget(app);
%             imagesc(app.UIAxes_5, dispTargetArr)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end            
            
            thisTargetNum = 0;
            selection = [0 0 0 0];
            for i = 1:4
                if app.BeamSelectionButtonGroup.Buttons(i).Value
                    thisTargetNum = i;
                    selection(i) = 1;
                    break
                end
            end
%             sprintf('Selection: %i', i)
            thisTarget = app.targetArrSmall(:, :, thisTargetNum) ;
            thisTarget = circshift(thisTarget, round(app.incShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, thisTargetNum) = thisTarget;

            imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
                + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            
            app.config.targShiftY(i) = app.config.targShiftY(i)+app.incShift;
            app.ConsoleTextArea.Value = ['Target shifted down.'; num2str(app.config.targShiftY); app.ConsoleTextArea.Value];
            posSet = func(app, selection);
            
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app,i);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             dispTargetArr = setDispTarget(app);
%             imagesc(app.UIAxes_5, dispTargetArr)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            
            thisTargetNum = 0;
            selection = [0 0 0 0];
            for i = 1:4
                if app.BeamSelectionButtonGroup.Buttons(i).Value
                    thisTargetNum = i;
                    selection(i) = 1;
                    break
                end
            end
%             sprintf('Selection: %i', i)
            thisTarget = app.targetArrSmall(:, :, thisTargetNum) ;
            thisTarget = circshift(thisTarget, -round(app.incShift/app.sizeRatio), 2);
            app.targetArrSmall(:, :, thisTargetNum) = thisTarget;
            imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
                + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            
            app.config.targShiftX(i) = app.config.targShiftX(i)-app.incShift;
            app.ConsoleTextArea.Value = ['Target shifted left.'; num2str(app.config.targShiftX); app.ConsoleTextArea.Value];
            posSet = func(app, selection);
            
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app,i);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             dispTargetArr = setDispTarget(app);
%             imagesc(app.UIAxes_5, dispTargetArr)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end

            thisTargetNum = 0;
            selection = [0 0 0 0];
            for i = 1:4
                if app.BeamSelectionButtonGroup.Buttons(i).Value
                    thisTargetNum = i;
                    selection(i) = 1;
                    break
                end
            end
%             sprintf('Selection: %i', i)
            thisTarget = app.targetArrSmall(:, :, thisTargetNum) ;
            thisTarget = circshift(thisTarget, round(app.incShift/app.sizeRatio), 2);
            app.targetArrSmall(:, :, thisTargetNum) = thisTarget;
            imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
                + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            
            app.config.targShiftX(i) = app.config.targShiftX(i)+app.incShift;
            app.ConsoleTextArea.Value = ['Target shifted right.'; num2str(app.config.targShiftX); app.ConsoleTextArea.Value];
            posSet = func(app, selection);
            
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app,i);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             dispTargetArr = setDispTarget(app);
%             imagesc(app.UIAxes_5, dispTargetArr)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Value changed function: PixelsSlider
        function PixelsSliderValueChanged(app, event)
            value = app.PixelsSlider.Value;
            app.incShift = int16(value);
        end

        % Callback function
        function SCPButtonPushed(app, event)
        disp('Function disabled!')

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% if ~app.appConfig.MPUS_PC
%             if ~isfile(app.outFile)
%                 disp('File not written yet. Please save the file')
%                 return
%             end
% 
%             MPUS_IP = 'Rowlab-MPUS:';
%             MPUS_Dir = strcat('C:\Users\Glenn\repositories\holoApp\img\out\SLMphase_');
%             phaseFile = strcat(app.config.targetTitle, '.bmp');
%             command = string(strcat('scp ', {' '}, app.outFile, ...
%             string({' Glenn@'}), MPUS_IP, MPUS_Dir, ...
%             app.saveTitle, '.bmp'));
%             cmdStr = sprintf('Trying command...\n %s', command);
%             disp(cmdStr)
%             status = 0;
%             [status, cmdout] = system(command);
%             if status
%                 disp('Command failed!')
%                 disp(cmdout)
%             else
%                 disp('scp successful!!')
%             end
%             return
%         end
%         
%         disp('This is RowLab-MPUS!')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)

            
            if sum(size(app.totPhase)) == 0
                disp('Nothing to save!')
            else
                saveImg(app);
                sprintf('Current image saved: %s', app.outFile)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
%                 phaseSave = app.totPhase(app.config.apPaddingY:app.config.apPaddingY+app.config.slmDim(1)-1, ...
%                      app.config.apPaddingX:app.config.apPaddingX+app.config.slmDim(2)-1);
%                 app.currentImg = uint8((phaseSave).*255./(2*pi));
%                 if app.appConfig.MPUS_PC
%                     app.outFile = strcat('C:\Users\Glenn\repositories\holoApp\img\out\SLMphase_', app.saveTitle, '.bmp');
%                     imwrite(app.currentImg, app.outFile)
%                     disp('Phase map saved (RowLab-MPUS)')
%                 else
%                     app.outFile = strcat('C:\Users\glenn\repositories\holoApp\img\out\SLMphase_', app.saveTitle, '.bmp');
%                     imwrite(app.currentImg, app.outFile)
%                     disp('Phase map saved (local)')
%                 end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
            end
        end

        % Value changed function: Beam1XEditField
        function Beam1XEditFieldValueChanged(app, event)
            value = app.Beam1XEditField.Value;
            
        end

        % Button pushed function: SetButton_1
        function SetButton_1Pushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            thisTarget = app.targetArrSmall(:, :, 1);
            selection = [1 0 0 0];
            xShift = app.Beam1XEditField.Value-app.config.targShiftX(1);
            yShift = app.Beam1YEditField.Value-app.config.targShiftY(1);
            thisTarget = circshift(thisTarget, round(xShift/app.sizeRatio), 2);
            thisTarget = circshift(thisTarget, round(yShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, 1) = thisTarget;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
%                 + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.config.targShiftX(1) = app.config.targShiftX(1) + xShift;
            app.config.targShiftY(1) = app.config.targShiftY(1) + yShift;
            posSet = func(app, selection);
            dispTargetArr = setDispTarget(app);
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app, 1)
            end
            imagesc(app.UIAxes_5, dispTargetArr)
        end

        % Button pushed function: SetButton_4
        function SetButton_4Pushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end 
            disp(size(app.targetArrSmall))
            thisTarget = app.targetArrSmall(:, :, 4);
            selection = [0 0 0 1];
            xShift = app.Beam4XEditField.Value-app.config.targShiftX(4);
            yShift = app.Beam4YEditField.Value-app.config.targShiftY(4);
            thisTarget = circshift(thisTarget, round(xShift/app.sizeRatio), 2);
            thisTarget = circshift(thisTarget, round(yShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, 4) = thisTarget;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
%                 + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.config.targShiftX(4) = app.config.targShiftX(4) + xShift;
            app.config.targShiftY(4) = app.config.targShiftY(4) + yShift;
            posSet = func(app, selection);
            dispTargetArr = setDispTarget(app);
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app, 4)
            end
            imagesc(app.UIAxes_5, dispTargetArr)
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            thisTarget = app.targetArrSmall(:, :, 2);
            selection = [0 1 0 0];
            xShift = app.Beam2XEditField.Value-app.config.targShiftX(2);
            yShift = app.Beam2YEditField.Value-app.config.targShiftY(2);
            thisTarget = circshift(thisTarget, round(xShift/app.sizeRatio), 2);
            thisTarget = circshift(thisTarget, round(yShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, 2) = thisTarget;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
%                 + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.config.targShiftX(2) = app.config.targShiftX(2) + xShift;
            app.config.targShiftY(2) = app.config.targShiftY(2) + yShift;
            posSet = func(app, selection);
            dispTargetArr = setDispTarget(app);
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app, 2)
            end
            imagesc(app.UIAxes_5, dispTargetArr)
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            if ~app.appConfig.geomSet
                disp('Geometry not set')
                return
            end
            thisTarget = app.targetArrSmall(:, :, 3);
            selection = [0 0 1 0];
            xShift = app.Beam3XEditField.Value-app.config.targShiftX(3);
            yShift = app.Beam3YEditField.Value-app.config.targShiftY(3);
            thisTarget = circshift(thisTarget, round(xShift/app.sizeRatio), 2);
            thisTarget = circshift(thisTarget, round(yShift/app.sizeRatio), 1);
            app.targetArrSmall(:, :, 3) = thisTarget;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             imagesc(app.UIAxes_5, imresize(app.targetArrSmall(:,:,1) + app.targetArrSmall(:,:,2) ...
%                 + app.targetArrSmall(:,:,3) + app.targetArrSmall(:,:,4), [500 500]))
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.config.targShiftX(3) = app.config.targShiftX(3) + xShift;
            app.config.targShiftY(3) = app.config.targShiftY(3) + yShift;
            posSet = func(app, selection);
            dispTargetArr = setDispTarget(app);
            if app.LiveControlSwitch.Value == 1
                liveSet = setLiveImg(app, 3)
            end
            imagesc(app.UIAxes_5, dispTargetArr)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.APIInitializedLamp.Color == [0, 1, 0]
                calllib('Blink_C_wrapper', 'Delete_SDK');
                disp('Deleting SDK and closing app')
            else
                disp('Closing app. No SDK present.')
            end
            delete(app)
            
        end

        % Button pushed function: Apply
        function ApplyButtonPushed(app, event)

            if ~app.appConfig.MPUS_PC
                disp('This is not RowLab-MPUS!')
                return
            end
            if app.SDK.init == 0
                disp('SDK not initialized!')
                return
            end
            saveImg(app);
            if ~app.LiveControlSwitch.Value == 1
                if ~isfile(app.outFile)
                    disp('No image to apply!')
                    return
                end
            end
            disp('Applying Image.')
            ImageOne = libpointer('uint8Ptr', zeros(app.SDK.height*app.SDK.bytes, app.SDK.width*app.SDK.bytes));
            ImageOne.value = app.currentImg;
            if size(app.currentImg) ~= [1152 1920]
                disp('Image not set!')
                return
            end
%             imagesc(app.UIAxes_5, imresize(ImageOne.value, [1000 1000]))
            ImageOne.value = flip(imrotate(ImageOne.value, 90));
            calllib('Blink_C_wrapper', 'Write_image', app.SDK.board_number, ImageOne, app.SDK.width*app.SDK.height*app.SDK.bytes, ...
                app.SDK.wait_For_Trigger, app.SDK.flip_immediate, app.SDK.OutputPulseImageFlip, app.SDK.OutputPulseImageRefresh, app.SDK.timeout_ms);
            calllib('Blink_C_wrapper', 'ImageWriteComplete', app.SDK.board_number, app.SDK.timeout_ms);  
            disp('Image applied.')
        end

        % Button pushed function: StartingPositions
        function StartingPositionsButtonPushed(app, event)
            app.Beam1XEditField.Value = app.config.goalPosX(1)-app.Beam1StartingXEditField.Value;
            app.Beam1YEditField.Value = app.config.goalPosY(1)-app.Beam1StartingYEditField.Value;
            app.Beam2XEditField.Value = app.config.goalPosX(2)-app.Beam2StartingXEditField.Value;
            app.Beam2YEditField.Value = app.config.goalPosY(2)-app.Beam2StartingYEditField.Value;
            app.Beam3XEditField.Value = app.config.goalPosX(3)-app.Beam3StartingXEditField.Value;
            app.Beam3YEditField.Value = app.config.goalPosY(3)-app.Beam3StartingYEditField.Value;
            app.Beam4XEditField.Value = app.config.goalPosX(4)-app.Beam4StartingXEditField.Value;
            app.Beam4YEditField.Value = app.config.goalPosY(4)-app.Beam4StartingYEditField.Value;
            
            app.SetButton_1Pushed(app)
            app.SetButton_4Pushed(app)
            app.SetButton_2Pushed(app)
            app.SetButton_3Pushed(app)

        end

        % Button pushed function: SetPositions_2
        function SetPositions_2ButtonPushed(app, event)
            app.SetButton_1Pushed(app)
            app.SetButton_4Pushed(app)
            app.SetButton_2Pushed(app)
            app.SetButton_3Pushed(app)
        end

        % Button pushed function: SetFile
        function SetFileButtonPushed(app, event)
            file = app.FileNameEditField.Value;
            directory = app.DirectoryEditField.Value;
            app.outFile = strcat(directory, file, '.bmp');
            disp('File name set.')
        end

        % Value changed function: MultipletargetsCheckBox
        function MultipletargetsCheckBoxValueChanged(app, event)
            value = app.MultipletargetsCheckBox.Value;
            app.appConfig.multipleTargets = value;
            app.config.mutargetMult = value;
            sprintf("Multiple targets check changed to %i.", value)
        end

        % Value changed function: DecreasingsizesCheckBox
        function DecreasingsizesCheckBoxValueChanged(app, event)
            value = app.DecreasingsizesCheckBox.Value;
            app.appConfig.decreaseSize = value;
        end

        % Value changed function: CirclePartsCheckBox
        function CirclePartsCheckBoxValueChanged(app, event)
            value = app.CirclePartsCheckBox.Value;
            app.appConfig.circPartTargets = value;
            app.config.do_circParts = value;
%             sprintf('app.config circParts: %i', app.config.do_circParts)
%             sprintf('app.Appconfig circParts: %i', app.appConfig.circPartTargets)
        end

        % Value changed function: TargetDisplaySwitch
        function TargetDisplaySwitchValueChanged(app, event)
            value = app.TargetDisplaySwitch.Value;

            sprintf('Switch type: %s', class(value))
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            value = app.FilesListBox.Value;
            thisFile = strcat(app.outDir, value);
            thisImg = imread(thisFile);
            app.currentImg = thisImg;
            app.outFile = thisFile;
            sprintf('Current image selected: %s', thisFile)
            value = app.LiveControlSwitch.Value;
            if value == 1
                disp(thisFile)
                app.baseImg = thisImg;
                imagesc(app.UIAxes_6, thisImg)
            end
        end

        % Button pushed function: RefreshFilesButton
        function RefreshFilesButtonPushed(app, event)
            filesList = dir(app.outDir);
            app.FilesListBox.Items = {filesList.name}';
        end

        % Value changed function: LiveControlSwitch
        function LiveControlSwitchValueChanged(app, event)
            value = app.LiveControlSwitch.Value;
            if value == 1
                disp(app.outFile)
                app.baseImg = imread(app.outFile);
                app.LiveControlLamp.Color = [0,1,0];
            else
                app.LiveControlLamp.Color = [0,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_5 = uiaxes(app.UIFigure);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Hologram Generation Control App';
            app.ImageDisplay1Label = uilabel(app.UIFigure);
            app.ImageDisplay1Label.Text = 'Image Display 1';
            app.ImageDisplay2Label = uilabel(app.UIFigure);
            app.ImageDisplay2Label.Text = 'Image Display 2';
            app.BeamControlPanel = uipanel(app.UIFigure);
            app.BeamControlPanel.Title = 'Beam Control';
            app.BeamMovementPanel = uipanel(app.BeamControlPanel);
            app.BeamMovementPanel.Title = 'Beam Movement';
            app.UpButton = uibutton(app.BeamMovementPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.BeamMovementPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.BeamMovementPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.BeamMovementPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.PixelsSliderLabel = uilabel(app.BeamMovementPanel);
            app.PixelsSliderLabel.Text = 'Pixels';
            app.PixelsSlider = uislider(app.BeamMovementPanel);
            app.PixelsSlider.ValueChangedFcn = createCallbackFcn(app, @PixelsSliderValueChanged, true);
            app.PixelsSlider.Value = 50;
            app.BeamSelectionButtonGroup = uibuttongroup(app.BeamControlPanel);
            app.BeamSelectionButtonGroup.Title = 'Beam Selection';
            app.Beam1Button = uitogglebutton(app.BeamSelectionButtonGroup);
            app.Beam1Button.Text = 'Beam 1';
            app.Beam1Button.Value = true;
            app.Beam2Button = uitogglebutton(app.BeamSelectionButtonGroup);
            app.Beam2Button.Text = 'Beam 2';
            app.Beam3Button = uitogglebutton(app.BeamSelectionButtonGroup);
            app.Beam3Button.Text = 'Beam 3';
            app.Beam4Button = uitogglebutton(app.BeamSelectionButtonGroup);
            app.Beam4Button.Text = 'Beam 4';
            app.IOControlPanel = uipanel(app.UIFigure);
            app.IOControlPanel.Title = 'I/O Control';
            app.Init = uibutton(app.IOControlPanel, 'push');
            app.Init.ButtonPushedFcn = createCallbackFcn(app, @InitPushed, true);
            app.Init.Text = 'Initialize';
            app.LoadTargetButton = uibutton(app.IOControlPanel, 'push');
            app.LoadTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTargetButtonPushed, true);
            app.LoadTargetButton.Text = 'Load Target';
            app.InvertTargetButton = uibutton(app.IOControlPanel, 'push');
            app.InvertTargetButton.ButtonPushedFcn = createCallbackFcn(app, @InvertTargetButtonPushed, true);
            app.InvertTargetButton.Text = 'Invert Target';
            app.GenerateButton = uibutton(app.IOControlPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Save = uibutton(app.IOControlPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save to File';
            app.MPUS_PCLabel = uilabel(app.IOControlPanel);
            app.MPUS_PCLabel.Text = 'MPUS_PC';
            app.MPUSLamp = uilamp(app.IOControlPanel);
            app.APIInitializedLampLabel = uilabel(app.IOControlPanel);
            app.APIInitializedLampLabel.Text = 'API Initialized';
            app.APIInitializedLamp = uilamp(app.IOControlPanel);
            app.SLMConnectedLampLabel = uilabel(app.IOControlPanel);
            app.SLMConnectedLampLabel.Text = 'SLM Connected';
            app.SLMConnectedLamp = uilamp(app.IOControlPanel);
            app.Apply = uibutton(app.IOControlPanel, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.Apply.Text = 'Apply';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BeamInfoTab = uitab(app.TabGroup);
            app.BeamInfoTab.Title = ' Beam Info';
            app.Beam3XEditField_2Label = uilabel(app.BeamInfoTab);
            app.Beam3XEditField_2Label.Text = 'Beam 3 X';
            app.Beam3XEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.Beam3YEditField_2Label = uilabel(app.BeamInfoTab);
            app.Beam3YEditField_2Label.Text = 'Beam 3 Y';
            app.Beam3YEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.Beam2XEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam2XEditFieldLabel.Text = 'Beam 2 X';
            app.Beam2XEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.Beam2YEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam2YEditFieldLabel.Text = 'Beam 2 Y';
            app.Beam2YEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.SetButton_3 = uibutton(app.BeamInfoTab, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.SetButton_2 = uibutton(app.BeamInfoTab, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.StartingPositions = uibutton(app.BeamInfoTab, 'push');
            app.StartingPositions.ButtonPushedFcn = createCallbackFcn(app, @StartingPositionsButtonPushed, true);
            app.StartingPositions.Text = 'Starting Positions';
            app.SetGeometry = uibutton(app.BeamInfoTab, 'push');
            app.SetGeometry.ButtonPushedFcn = createCallbackFcn(app, @SetGeometryPushed, true);
            app.SetGeometry.Text = {'Set Geometry'; ''};
            app.SetButton_4 = uibutton(app.BeamInfoTab, 'push');
            app.SetButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetButton_4Pushed, true);
            app.SetButton_4.Text = 'Set';
            app.Beam4XEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam4XEditFieldLabel.Text = 'Beam 4 X';
            app.Beam4XEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.Beam4YEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam4YEditFieldLabel.Text = 'Beam 4 Y';
            app.Beam4YEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.SetPositions_2 = uibutton(app.BeamInfoTab, 'push');
            app.SetPositions_2.ButtonPushedFcn = createCallbackFcn(app, @SetPositions_2ButtonPushed, true);
            app.SetPositions_2.Text = 'Set All Positions';
            app.SetButton_1 = uibutton(app.BeamInfoTab, 'push');
            app.SetButton_1.ButtonPushedFcn = createCallbackFcn(app, @SetButton_1Pushed, true);
            app.SetButton_1.Text = 'Set';
            app.Beam1XEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam1XEditFieldLabel.Text = 'Beam 1 X';
            app.Beam1XEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.Beam1XEditField.ValueChangedFcn = createCallbackFcn(app, @Beam1XEditFieldValueChanged, true);
            app.Beam1YEditFieldLabel = uilabel(app.BeamInfoTab);
            app.Beam1YEditFieldLabel.Text = 'Beam 1 Y';
            app.Beam1YEditField = uieditfield(app.BeamInfoTab, 'numeric');
            app.ConsoleTab = uitab(app.TabGroup);
            app.ConsoleTab.Title = 'Console';
            app.ConsoleTextAreaLabel = uilabel(app.ConsoleTab);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.ConsoleTab);
            app.ConsoleTextArea.Placeholder = 'placeholder';
            app.ConsoleTextArea.Value = {'Check here for all your outputs and updates!'; ''; ''; '(Because you are an idiot and need constant debugging.)'; ''; ''};
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SceneSettingsTab = uitab(app.TabGroup2);
            app.SceneSettingsTab.Title = 'Scene Settings';
            app.BeamGeometryLabel = uilabel(app.SceneSettingsTab);
            app.BeamGeometryLabel.Text = 'Beam Geometry';
            app.Beam1StartingXEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.Beam1StartingXEditFieldLabel.Text = 'Beam 1 Starting X';
            app.Beam1StartingXEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam1StartingYEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.Beam1StartingYEditFieldLabel.Text = 'Beam 1 Starting Y';
            app.Beam1StartingYEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam4StartingXEditField_2Label = uilabel(app.SceneSettingsTab);
            app.Beam4StartingXEditField_2Label.Text = 'Beam 4 Starting X';
            app.Beam4StartingXEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam4StartingYEditField_2Label = uilabel(app.SceneSettingsTab);
            app.Beam4StartingYEditField_2Label.Text = 'Beam 4 Starting Y';
            app.Beam4StartingYEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam3StartingXEditField_2Label = uilabel(app.SceneSettingsTab);
            app.Beam3StartingXEditField_2Label.Text = 'Beam 3 Starting X';
            app.Beam3StartingXEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam3StartingYEditField_2Label = uilabel(app.SceneSettingsTab);
            app.Beam3StartingYEditField_2Label.Text = 'Beam 3 Starting Y';
            app.Beam3StartingYEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam2StartingXEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.Beam2StartingXEditFieldLabel.Text = 'Beam 2 Starting X';
            app.Beam2StartingXEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.Beam2StartingYEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.Beam2StartingYEditFieldLabel.Text = 'Beam 2 Starting Y';
            app.Beam2StartingYEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.BeamColEditField_2Label = uilabel(app.SceneSettingsTab);
            app.BeamColEditField_2Label.Text = '# Beam Col';
            app.BeamColEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.BeamColEditField.Value = 2;
            app.BeamRowEditField_2Label = uilabel(app.SceneSettingsTab);
            app.BeamRowEditField_2Label.Text = '# Beam Row';
            app.BeamRowEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.BeamRowEditField.Value = 2;
            app.FocalLengthmmEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length (mm)';
            app.FocalLengthmmEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.FocalLengthmmEditField.Value = 250;
            app.BeamDiametermmEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.BeamDiametermmEditFieldLabel.Text = 'Beam Diameter (mm)';
            app.BeamDiametermmEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.BeamDiametermmEditField.Value = 6;
            app.SingleTargetSizepxLabel = uilabel(app.SceneSettingsTab);
            app.SingleTargetSizepxLabel.Text = 'Single Target Size (px)';
            app.SingleTargetSizepxEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.SingleTargetSizepxEditField.Value = 1000;
            app.GaussSigmaEditField_2Label = uilabel(app.SceneSettingsTab);
            app.GaussSigmaEditField_2Label.Text = 'Gauss Sigma';
            app.GaussSigmaEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.GaussSigmaEditField.Value = 600;
            app.FileInfoLabel = uilabel(app.SceneSettingsTab);
            app.FileInfoLabel.Text = 'File Info';
            app.DirectoryEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SceneSettingsTab, 'text');
            app.DirectoryEditField.Value = '..\img\out\';
            app.FileNameEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SceneSettingsTab, 'text');
            app.FileNameEditField.Value = 'Overlap Test 1';
            app.SetFile = uibutton(app.SceneSettingsTab, 'push');
            app.SetFile.ButtonPushedFcn = createCallbackFcn(app, @SetFileButtonPushed, true);
            app.SetFile.Text = 'Set File';
            app.MultiTargetMaxSizepxEditFieldLabel = uilabel(app.SceneSettingsTab);
            app.MultiTargetMaxSizepxEditFieldLabel.Text = 'Multi Target Max Size (px)';
            app.MultiTargetMaxSizepxEditField = uieditfield(app.SceneSettingsTab, 'numeric');
            app.MultiTargetMaxSizepxEditField.Value = 5000;
            app.Panel = uipanel(app.SceneSettingsTab);
            app.MultipletargetsCheckBox = uicheckbox(app.Panel);
            app.MultipletargetsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipletargetsCheckBoxValueChanged, true);
            app.MultipletargetsCheckBox.Text = ' Multiple targets';
            app.DecreasingsizesCheckBox = uicheckbox(app.Panel);
            app.DecreasingsizesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecreasingsizesCheckBoxValueChanged, true);
            app.DecreasingsizesCheckBox.Text = ' Decreasing sizes';
            app.CirclePartsCheckBox = uicheckbox(app.Panel);
            app.CirclePartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CirclePartsCheckBoxValueChanged, true);
            app.CirclePartsCheckBox.Text = 'Circle Parts';
            app.CirclePartsCheckBox.Value = true;
            app.TargetDisplaySwitchLabel = uilabel(app.Panel);
            app.TargetDisplaySwitchLabel.Text = 'Target Display';
            app.TargetDisplaySwitch = uiswitch(app.Panel, 'slider');
            app.TargetDisplaySwitch.Items = {'Separate', 'Overlap'};
            app.TargetDisplaySwitch.ItemsData = [0 1];
            app.TargetDisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @TargetDisplaySwitchValueChanged, true);
            app.TargetDisplaySwitch.Value = 0;
            app.ImagesTab = uitab(app.TabGroup2);
            app.ImagesTab.Title = 'Images';
            app.FilesListBoxLabel = uilabel(app.ImagesTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.ImagesTab);
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.RefreshFilesButton = uibutton(app.ImagesTab, 'push');
            app.RefreshFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilesButtonPushed, true);
            app.RefreshFilesButton.Text = 'Refresh Files';
            app.BeamTargetsTab = uitab(app.TabGroup2);
            app.BeamTargetsTab.Title = 'Beam Targets';
            app.SeparateBeamTargetsPanel = uipanel(app.BeamTargetsTab);
            app.SeparateBeamTargetsPanel.Title = 'Separate Beam Targets';
            app.UIAxes_10 = uiaxes(app.SeparateBeamTargetsPanel);
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_8 = uiaxes(app.SeparateBeamTargetsPanel);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.SeparateBeamTargetsPanel);
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_7 = uiaxes(app.SeparateBeamTargetsPanel);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.Beam3TargetLabel = uilabel(app.SeparateBeamTargetsPanel);
            app.Beam3TargetLabel.Text = 'Beam 3 Target';
            app.Beam4TargetLabel = uilabel(app.SeparateBeamTargetsPanel);
            app.Beam4TargetLabel.Text = 'Beam 4 Target';
            app.Beam2TargetLabel = uilabel(app.SeparateBeamTargetsPanel);
            app.Beam2TargetLabel.Text = 'Beam 2 Target';
            app.Beam1TargetLabel = uilabel(app.SeparateBeamTargetsPanel);
            app.Beam1TargetLabel.Text = 'Beam 1 Target';
            app.GeometryDisplayTab = uitab(app.TabGroup2);
            app.GeometryDisplayTab.Title = 'Geometry Display';
            app.SLMGeometryPanel = uipanel(app.GeometryDisplayTab);
            app.SLMGeometryPanel.Title = 'SLM Geometry';
            app.UIAxes_3 = uiaxes(app.SLMGeometryPanel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_2 = uiaxes(app.SLMGeometryPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_4 = uiaxes(app.SLMGeometryPanel);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_1 = uiaxes(app.SLMGeometryPanel);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.SLMIlluminationLabel = uilabel(app.SLMGeometryPanel);
            app.SLMIlluminationLabel.Text = 'SLM Illumination';
            app.SLMPhaseLabel = uilabel(app.SLMGeometryPanel);
            app.SLMPhaseLabel.Text = 'SLM Phase';
            app.TargetPatternLabel = uilabel(app.SLMGeometryPanel);
            app.TargetPatternLabel.Text = 'Target Pattern';
            app.SceneGeometryLabel = uilabel(app.SLMGeometryPanel);
            app.SceneGeometryLabel.Text = 'Scene Geometry';
            app.LiveControlPanel = uipanel(app.UIFigure);
            app.LiveControlPanel.Title = 'Live Control';
            app.LiveControlLamp = uilamp(app.LiveControlPanel);
            app.LiveControlSwitch = uiswitch(app.LiveControlPanel, 'toggle');
            app.LiveControlSwitch.ItemsData = [0 1];
            app.LiveControlSwitch.ValueChangedFcn = createCallbackFcn(app, @LiveControlSwitchValueChanged, true);
            app.LiveControlSwitch.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = holoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end