classdef offset_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.EditField
        VerticalSlider            matlab.ui.control.Slider
        VerticalSliderLabel       matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        HorizontalSlider          matlab.ui.control.Slider
        HorizontalSliderLabel     matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        DiametermmEditField       matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        dims
        tgt_img
        centre
        horCentre
        vertCentre
        % horOffset
        % vertOffset
        offset
        pitch
        diam
        diam_in_pixels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dims = [1152,1920];
            app.tgt_img = zeros([app.dims 3]);
            app.centre = [961,577];
            app.offset = [0 0];
            % app.vertOffset = 0;
            app.pitch = 9.2e-6;
            app.diam = app.DiametermmEditField.Value*1e-3;
            app.diam_in_pixels = round(app.diam/app.pitch);
            
            app.EditField.Value = num2str(0);
            app.EditField_2.Value = num2str(0);

            % imshow(app.tgt_img,'Parent',app.UIAxes);
            % hold(app.UIAxes,'on')
            h=insertShape(app.tgt_img,'filled-circle',[app.centre+app.offset round(app.diam_in_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            imshow(h,'Parent',app.UIAxes);
        end

        % Value changed function: VerticalSlider
        function VerticalSliderValueChanged(app, event)
            value = app.VerticalSlider.Value;
            app.offset(2) = round(value);% +: right and down, -: left and up
            app.EditField.Value = num2str(app.offset(2));
        end

        % Value changed function: HorizontalSlider
        function HorizontalSliderValueChanged(app, event)
            value = app.HorizontalSlider.Value;
            app.offset(1) = round(value);% +: right and down, -: left and up
            app.EditField_2.Value = num2str(app.offset(1));
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            h=insertShape(app.tgt_img,'filled-circle',[app.centre+app.offset round(app.diam_in_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            imshow(h,'Parent',app.UIAxes);
            
        end

        % Value changed function: DiametermmEditField
        function DiametermmEditFieldValueChanged(app, event)
            value = app.DiametermmEditField.Value;
            app.diam = value*1e-3;
            app.diam_in_pixels = round(app.diam/app.pitch);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.offset(2) = round(str2double(value));
            app.VerticalSlider.Value = app.offset(2);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.offset(1) = round(str2double(value));
            app.HorizontalSlider.Value = app.offset(1);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {375, 375};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HorizontalSliderLabel = uilabel(app.LeftPanel);
            app.HorizontalSliderLabel.Text = 'Horizontal';
            app.HorizontalSlider = uislider(app.LeftPanel);
            app.HorizontalSlider.Limits = [-960 961];
            app.HorizontalSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalSliderValueChanged, true);
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.VerticalSliderLabel = uilabel(app.LeftPanel);
            app.VerticalSliderLabel.Text = 'Vertical';
            app.VerticalSlider = uislider(app.LeftPanel);
            app.VerticalSlider.Limits = [-576 575];
            app.VerticalSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalSliderValueChanged, true);
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Target Image')
            zlabel(app.UIAxes, 'Z')
            app.DiametermmEditFieldLabel = uilabel(app.RightPanel);
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.DiametermmEditField = uieditfield(app.RightPanel, 'numeric');
            app.DiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @DiametermmEditFieldValueChanged, true);
            app.DiametermmEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = offset_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end